classdef flight_search_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FlightSearchAppLabel            matlab.ui.control.Label
        DepartureAirportEditFieldLabel  matlab.ui.control.Label
        DepartureAirportEditField       matlab.ui.control.EditField
        ArrivalAirportEditFieldLabel    matlab.ui.control.Label
        ArrivalAirportEditField         matlab.ui.control.EditField
        FlightTextAreaLabel             matlab.ui.control.Label
        FlightTextArea                  matlab.ui.control.TextArea
        SearchButton                    matlab.ui.control.Button
        SelectAirlineCheckBox           matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        DepartureInformationTextAreaLabel  matlab.ui.control.Label
        DepartureInformationTextArea    matlab.ui.control.TextArea
        AirlineTextAreaLabel            matlab.ui.control.Label
        AirlineTextArea                 matlab.ui.control.TextArea
        AircraftTextAreaLabel           matlab.ui.control.Label
        AircraftTextArea                matlab.ui.control.TextArea
        ArrivalInformationTextAreaLabel  matlab.ui.control.Label
        ArrivalInformationTextArea      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        DSTInformationLabel             matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_5                      matlab.ui.control.TextArea
        TextArea_6                      matlab.ui.control.TextArea
        MarkChernyshov2018Label         matlab.ui.control.Label
    end

    properties (Access = private)
        check_box % Value of checkbox
        rv1 % Routes output
        rv2 % Departure airport info output
        rv3 % Arrival airport info output
        rv4 % Airline info output
        rv5 % Aircraft info output
        convertedplanes % variable for converted planes
        splitplanes % value for split plane array
        lengthplanes % value for length of plane array
    end

    methods (Access = private)

        % Value changed function: SelectAirlineCheckBox
        function SelectAirlineCheckBoxValueChanged(app, event)
            app.check_box = app.SelectAirlineCheckBox.Value; % value shows if checkbox is pressed           
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
        routes = readtable('updated_routes.txt'); % import routes data
        planes = readtable('updated_planes.txt'); % import planes data
        airlines = readtable('updated_airlines.txt'); % import airlines data
        airports = readtable('updated_airports.txt'); % import airport data

        % Change headers for planes table
        planes.Properties.VariableNames{1} = 'Name'; % change first column to Name
        planes.Properties.VariableNames{2} = 'IATA'; % change second column to IATA
        planes.Properties.VariableNames{3} = 'ICAO'; % change third column to ICAO
        
        if app.check_box == 1 % User inputs Departure, Arrival and Airline
            % Check if input information is correct

            if length(app.DropDown.Value) ~= 2 % check if airline name is correct
                error('Check the airline')
            end

            % Match the airport name with IATA code

            for l = 1:7184 % stop when gone through all possible
                if strcmp(airports{l, 2}, app.DepartureAirportEditField.Value) % match the airport name with IATA
                    app.rv2 = airports(l, [2, 3, 4, 5, 7, 8, 9, 10, 11, 12]); % return the matched row, print columns 2-5 & 7-13
                    temp1 = airports(l, :); % store IATA in temp1
                end
            end

            for m = 1:7184 % stop when gone through all possible
                if strcmp(airports{m, 2}, app.ArrivalAirportEditField.Value) % match the airport name with IATA
                    app.rv3 = airports(m, [2, 3, 4, 5, 7, 8, 9, 10, 11, 12]); % return the matched row, print columns 2-5 & 7-13
                    temp2 = airports(m, :); % store IATA in temp 2
                end
            end

            % Find the matching flight

            for i = 1:67663 % stop when gone through all possible 
                if strcmp(routes{i, 1}, app.DropDown.Value) && strcmp(routes{i, 3},temp1{1, 5}) && strcmp(routes{i, 5}, temp2{1, 5}) % find the matching row
                    app.rv1 = routes(i, [1 3 5 7]); % return the matched row, print columns 1,3,5,7
                    temp = routes(i, :); % information for planes output
                    tempplanes = temp{1, 9}; % save aircraft information
                end
            end

            % Find the matching airline information

            for j = 1:6162 % stop when gone through all possible
                if strcmp(airlines{j, 4}, app.DropDown.Value) % find the matching row
                    app.rv4 = airlines(j, [2 3 4 5 6 7]); % return the matched row, print columns 2-7
                end
            end
   
            app.convertedplanes = char(tempplanes); % converted planes cell array to char
            app.splitplanes = strsplit(app.convertedplanes); % split planes char
            app.lengthplanes = length(app.splitplanes); % find number of possible aircraft
    
            for n = 1:(app.lengthplanes) % find information for all available planes for certain route
        
                % Find the matching aircraft information
            
                for k = 1:174 % stop when gone through all possible
                    if strcmp(char(app.splitplanes(n)), planes{k, 2}) % find the matching ro
                        planesfinal(n, 1:3) = table2array(planes(k, 1:3)); % store all available planes in planesfinal
                        app.rv5 = planesfinal; % return the table with all possible planes
                    end
                end
            end
    
        else % User inputs Departure and Arrival
    
            for l = 1:7184 % stop when gone through all possible
                if strcmp(airports{l, 2}, app.DepartureAirportEditField.Value) % match the airport name with IATA
                    app.rv2 = airports(l, [2, 3, 4, 5, 7, 8, 9, 10, 11, 12]); % return the matched row, print columns 2-5 & 7-13
                    temp1 = airports(l, :); % store IATA in temp1
                end
            end

            for m = 1:7184 % stop when gone through all possible
                if strcmp(airports{m, 2}, app.ArrivalAirportEditField.Value) % match the airport name with IATA
                    app.rv3 = airports(m, [2, 3, 4, 5, 7, 8, 9, 10, 11, 12]); % return the matched row, print columns 2-5 & 7-13
                    temp2 = airports(m, :); % store IATA in temp 2
                end
            end

            % Find the matching flight

            for i = 1:67663 % stop when gone through all possible 
                if strcmp(routes{i, 3},temp1{1, 5}) && strcmp(routes{i, 5}, temp2{1, 5}) % find the matching row
                    app.rv1 = routes(i, [1 3 5 7]); % return the matched row, print columns 1,3,5,7
                    temp = routes(i, :); % information for planes output
                    tempplanes = temp{1, 9}; % save aircraft information            
                end
            end

            for j = 1:6162 % stop when gone through all possible
                if strcmp(airlines{j, 4}, temp{1, 1}) % find the matching row
                    app.rv4 = airlines(j, [2 3 4 5 6 7]); % return the matched row, print columns 2-7
                end
            end
    
            app.convertedplanes = char(tempplanes); % converted planes cell array to char
            app.splitplanes = strsplit(app.convertedplanes); % split planes char
            app.lengthplanes = length(app.splitplanes); % find number of possible aircraft
    
            for n = 1:(app.lengthplanes) % find information for all available planes for certain route
        
                % Find the matching aircraft information
            
                for k = 1:174 % stop when gone through all possible
                    if strcmp(char(app.splitplanes(n)), planes{k, 2}) % find the matching row
                        planesfinal(n, 1:3) = table2array(planes(k, 1:3)); % store all available planes in planesfinal
                        app.rv5 = planesfinal; % return the table with all possible planes
                    end
                end
            end
        end
        FlightTextAreaValueChanged(app, event); % ouput flights information in text box
        DepartureInformationTextAreaValueChanged(app, event); % output departure airport information in text box
        ArrivalInformationTextAreaValueChanged(app, event); % output arrival airport information in text box
        AirlineTextAreaValueChanged(app, event); % output airline information in text box
        AircraftTextAreaValueChanged(app, event); % output aircraft information in text box
        end

        % Value changed function: FlightTextArea
        function FlightTextAreaValueChanged(app, event)
            %V = [app.rv1.Properties.VariableNames; table2cell(app.rv1)]; % convert information into string  
            %app.FlightTextArea.Value = strjoin(V); % join title string and information string for output
            app.FlightTextArea.Value = table2cell(app.rv1);
        end

        % Value changed function: DepartureInformationTextArea
        function DepartureInformationTextAreaValueChanged(app, event)
            temp = table2cell(app.rv2); % convert airport information table to cell array
            Name = temp(1);
            City = temp(2);
            Country = temp(3);
            IATA = temp(4);
            La = cell2mat(temp(5)); % convert cell to matrix
            Latitude = num2str(La); % convert matrix to string
            Lo = cell2mat(temp(6)); % convert cell to matrix
            Longitude = num2str(Lo); % convert matrix to string
            Al = cell2mat(temp(7)); % convert cell to matrix
            Altitude = num2str(Al); % convert matrix to string
            Timezone = temp(8);
            DST = temp(9);
            Tz = temp(10);
            T = table(Name, City, Country, IATA, Latitude, Longitude, Altitude, Timezone, DST, Tz); % create a table
            app.DepartureInformationTextArea.Value = table2cell(T); % convert table to cell array
        end

        % Value changed function: AirlineTextArea
        function AirlineTextAreaValueChanged(app, event)
            app.AirlineTextArea.Value = table2cell(app.rv4);
        end

        % Value changed function: AircraftTextArea
        function AircraftTextAreaValueChanged(app, event)
            length = app.lengthplanes; % copy length of aircraft array to length
            for n = 1:length % aircraft names are in app.rv5 from 1-length of aircraft array
                Name(1, n) = app.rv5(n); % take aircraft names from app.rv5 
            end
            N = strjoin(Name); % join all names into one string
            for l = (length + 1):(length*2) % IATA info is in app.rv5 from length+1 to length*2
                IATA(1, l) = app.rv5(l); % take IATA info from app.rv5
            end
            temp = IATA(1, (length+1):(length*2)); % remove empty space left from name in IATA array
            IA = strjoin(temp); % join all IATA info into one string
            for m = ((length*2)+1):(length*3) % ICAO info is in app.rv5 from (length*2)+1 to length*3
                ICAO(1, m) = app.rv5(m); % take ICAO info from app.rv5
            end
            temp1 = ICAO(1,((length*2)+1):(length*3)); % remove empty space left from name and IATA
            IC = strjoin(temp1); % join all ICAO info into one string
            T = table(N, IA, IC); % create a table from 3 strings
            app.AircraftTextArea.Value = table2cell(T); % save information to text window
        end

        % Value changed function: ArrivalInformationTextArea
        function ArrivalInformationTextAreaValueChanged(app, event)
            temp = table2cell(app.rv3); % convert airport information table to cell array
            Name = temp(1);
            City = temp(2);
            Country = temp(3);
            IATA = temp(4);
            La = cell2mat(temp(5)); % convert cell to matrix
            Latitude = num2str(La); % convert matrix to string
            Lo = cell2mat(temp(6)); % convert cell to matrix
            Longitude = num2str(Lo); % convert matrix to string
            Al = cell2mat(temp(7)); % convert cell to matrix
            Altitude = num2str(Al); % convert matrix to string
            Timezone = temp(8);
            DST = temp(9);
            Tz = temp(10);
            T = table(Name, City, Country, IATA, Latitude, Longitude, Altitude, Timezone, DST, Tz); % create a table
            app.ArrivalInformationTextArea.Value = table2cell(T); % convert table to cell array
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FlightSearchAppLabel = uilabel(app.UIFigure);
            app.FlightSearchAppLabel.Text = 'Flight Search App';
            app.DepartureAirportEditFieldLabel = uilabel(app.UIFigure);
            app.DepartureAirportEditFieldLabel.Text = 'Departure Airport';
            app.DepartureAirportEditField = uieditfield(app.UIFigure, 'text');
            app.ArrivalAirportEditFieldLabel = uilabel(app.UIFigure);
            app.ArrivalAirportEditFieldLabel.Text = 'Arrival Airport';
            app.ArrivalAirportEditField = uieditfield(app.UIFigure, 'text');
            app.FlightTextAreaLabel = uilabel(app.UIFigure);
            app.FlightTextAreaLabel.Text = 'Flight';
            app.FlightTextArea = uitextarea(app.UIFigure);
            app.FlightTextArea.ValueChangedFcn = createCallbackFcn(app, @FlightTextAreaValueChanged, true);
            app.FlightTextArea.Editable = 'off';
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search!';
            app.SelectAirlineCheckBox = uicheckbox(app.UIFigure);
            app.SelectAirlineCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAirlineCheckBoxValueChanged, true);
            app.SelectAirlineCheckBox.Text = 'Select Airline';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'AA', 'AF', 'AS', 'AZ', 'B6', 'BA', 'CX', 'DL', 'EK', 'F9', 'G4', 'HA', 'LH', 'NK', 'S7', 'SU', 'UA', 'WN', 'VS'};
            app.DropDown.Value = 'AF';
            app.DepartureInformationTextAreaLabel = uilabel(app.UIFigure);
            app.DepartureInformationTextAreaLabel.Text = 'Departure Information';
            app.DepartureInformationTextArea = uitextarea(app.UIFigure);
            app.DepartureInformationTextArea.ValueChangedFcn = createCallbackFcn(app, @DepartureInformationTextAreaValueChanged, true);
            app.DepartureInformationTextArea.Editable = 'off';
            app.AirlineTextAreaLabel = uilabel(app.UIFigure);
            app.AirlineTextAreaLabel.Text = 'Airline';
            app.AirlineTextArea = uitextarea(app.UIFigure);
            app.AirlineTextArea.ValueChangedFcn = createCallbackFcn(app, @AirlineTextAreaValueChanged, true);
            app.AirlineTextArea.Editable = 'off';
            app.AircraftTextAreaLabel = uilabel(app.UIFigure);
            app.AircraftTextAreaLabel.Text = 'Aircraft';
            app.AircraftTextArea = uitextarea(app.UIFigure);
            app.AircraftTextArea.ValueChangedFcn = createCallbackFcn(app, @AircraftTextAreaValueChanged, true);
            app.AircraftTextArea.Editable = 'off';
            app.ArrivalInformationTextAreaLabel = uilabel(app.UIFigure);
            app.ArrivalInformationTextAreaLabel.Text = 'Arrival Information';
            app.ArrivalInformationTextArea = uitextarea(app.UIFigure);
            app.ArrivalInformationTextArea.ValueChangedFcn = createCallbackFcn(app, @ArrivalInformationTextAreaValueChanged, true);
            app.ArrivalInformationTextArea.Editable = 'off';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'DST - Daylight Savings Time'; ''; ''; 'E - Europe'; 'A - US / Canada'; 'S - South America'; 'O - Australia'; 'Z - New Zealand'; 'N - None'; 'U - Uknown'};
            app.DSTInformationLabel = uilabel(app.UIFigure);
            app.DSTInformationLabel.Text = 'DST Information';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Name'; 'City'; 'Country'; 'IATA code'; 'Latitude'; 'Longitude'; 'Altitude'; 'Timezone'; 'DST'; 'Timezone'};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Name'; 'City'; 'Country'; 'IATA code'; 'Latitude'; 'Longitude'; 'Altitude'; 'Timezone'; 'DST'; 'Timezone'};
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'Airline'; 'Departure Airport'; 'Arrival Airport'; 'Codeshare'};
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'Name'; 'IATA code'; 'ICAO code'};
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.TextArea_6.Value = {'Name'; 'Alias (commonly known as)'; 'IATA code'; 'ICAO code'; 'Callsign'; 'Country'};
            app.MarkChernyshov2018Label = uilabel(app.UIFigure);
            app.MarkChernyshov2018Label.Text = '© Mark Chernyshov 2018';
        end
    end

    methods (Access = public)

        % Construct app
        function app = flight_search_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end